classdef SSFC_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartingOffsetCorrectionPanel   matlab.ui.container.Panel
        StartingOffsetCorrectionEditField  matlab.ui.control.NumericEditField
        ImageTilingPanel                matlab.ui.container.Panel
        NoFileSelectedLabel             matlab.ui.control.Label
        SelectPositionFileButton        matlab.ui.control.Button
        PixelSizeumEditField            matlab.ui.control.NumericEditField
        PixelSizeumEditFieldLabel       matlab.ui.control.Label
        SpectralBoundariesPanel         matlab.ui.container.Panel
        SpectralBoundariesnmTextArea    matlab.ui.control.TextArea
        SpectralBoundariesnmTextAreaLabel  matlab.ui.control.Label
        SpectralFindingGranularityEditField  matlab.ui.control.NumericEditField
        SpectralFindingGranularityEditFieldLabel  matlab.ui.control.Label
        AutomaticCheckBox_2             matlab.ui.control.CheckBox
        LineDetectionPanel              matlab.ui.container.Panel
        NumberofLinesinRawImageEditField  matlab.ui.control.NumericEditField
        NumberofLinesinRawImageEditFieldLabel  matlab.ui.control.Label
        AutomaticCheckBox               matlab.ui.control.CheckBox
        CalibrationSelectionPanel       matlab.ui.container.Panel
        NoFolderSelectedLabel_2         matlab.ui.control.Label
        SelectCalibrationFolderButton   matlab.ui.control.Button
        DataSelectionPanel              matlab.ui.container.Panel
        NoFolderSelectedLabel           matlab.ui.control.Label
        DataOrderDropDown               matlab.ui.control.DropDown
        DataOrderDropDownLabel          matlab.ui.control.Label
        SelectFoldertoProcessButton     matlab.ui.control.Button
        ProcessDataButton               matlab.ui.control.Button
        WavelengthRangenmPanel          matlab.ui.container.Panel
        MaximumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        SelectProcessingModeButtonGroup  matlab.ui.container.ButtonGroup
        VideoButton                     matlab.ui.control.ToggleButton
        ImageStackButton                matlab.ui.control.ToggleButton
        IndividualImagesButton          matlab.ui.control.ToggleButton
        OutputImageOptionsPanel         matlab.ui.container.Panel
        ImageSaveFileTypeButtonGroup    matlab.ui.container.ButtonGroup
        tifButton                       matlab.ui.control.RadioButton
        OutputImageBitDepthDropDown     matlab.ui.control.DropDown
        OutputImageBitDepthDropDownLabel  matlab.ui.control.Label
        SaveIntermediaryImagesCheckBox  matlab.ui.control.CheckBox
        SpectrallySplitSweptFieldConfocalProcessingLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        data_set_flag = 0;  % Flag to determine if data selection was made
        data_path = '';     % File Path to the Data Folder
        calibration_set_flag = 0;   % Flag for proper Calibration Folder 
        calibration_path = '';  % File Path to the calibration folder
        position_file_flag = 0; % Flag to determine if a position file selected 
        position_file_path = '';    % File Path to the Position File
        last_path = ''; % The last path selected, makes navigation convenient
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessDataButton
        function process_data(app, event)

            % Get Values
            proc_mode = '';
            if app.IndividualImagesButton.Value == 1
                proc_mode = 'Individual Images';
            elseif app.ImageStackButton.Value == 1
                proc_mode = 'Image Stack';
            elseif app.VideoButton.Value == 1
                proc_mode = 'Video';
            end

            save_intermediaries_flag = ...
                app.SaveIntermediaryImagesCheckBox.Value;

            img_save_type = '';
            if app.tifButton.Value == 1
                img_save_type = '.tif';
            end

            bit_depth = 0;
            switch app.OutputImageBitDepthDropDown.Value
                case '8 Bit'
                    bit_depth = 8;
                case '16 Bit'
                    bit_depth = 16;
                case '32 Bit'
                    bit_depth = 32;
                case '64 Bit'
                    bit_depth = 64;
            end

            data_order = app.DataOrderDropDown.Value;

            pixel_size = app.PixelSizeumEditField.Value;

            wavelength_range = [app.MinimumEditField.Value, ...
                app.MaximumEditField.Value];

            automated_line_detection_flag = ...
                app.AutomaticCheckBox.Value;

            num_bands = app.NumberofLinesinRawImageEditField.Value;

            automated_spectral_finding_flag = ...
                app.AutomaticCheckBox_2.Value;

            spectral_finding_granularity = ...
                app.SpectralFindingGranularityEditField.Value;
            
            offset_shift_correction = ...
                app.StartingOffsetCorrectionEditField.Value;

            spectral_boundary_temp = ...
                app.SpectralBoundariesnmTextArea.Value;
            spectral_boundary = zeros(1, numel(spectral_boundary_temp));
            for i = 1:numel(spectral_boundary_temp)
                spectral_boundary(i) = ...
                    str2double(spectral_boundary_temp{i});
            end


            % Check Processing is Valid
            if app.data_set_flag == 0
                uiwait(msgbox('Please Select a Valid Folder to Process.'));
                return;
            elseif (app.position_file_flag == 0) && ...
                    (~strcmp(proc_mode, 'Individual Images'))
                uiwait(msgbox('Please Select a Valid Position File'));
                return;
            elseif app.calibration_set_flag == 0
                uiwait(msgbox(...
                    'Please Select a Valid Folder of Calibration Files.'));
                return;
            else
                % Run Processing
                SSFC_Processing_Framework(proc_mode, ...
                    save_intermediaries_flag, img_save_type, bit_depth, ...
                    app.data_path, data_order, pixel_size, ...
                    app.position_file_path, app.calibration_path, ...
                    wavelength_range, automated_line_detection_flag, ...
                    num_bands, automated_spectral_finding_flag, ...
                    spectral_finding_granularity, spectral_boundary, ...
                    offset_shift_correction);
                % Close GUI
%                 delete(app);
            end
        end

        % Button pushed function: SelectFoldertoProcessButton
        function data_folder_selection(app, event)
            hpath = pwd;

            app.data_path = uigetdir(app.last_path, ...
                'Select Folder to Process');

            % Check if Sorted
            cd(app.data_path);
            dir_list = dir;
            dir_list = dir_list(3:end);
            sorted_flag = 0;
            for i = 1:numel(dir_list)
                if strcmp('Unprocessed Data', dir_list(i).name)
                    sorted_flag = 1;
                    break;
                end
            end

            % Check for .xml file
            rname = '';
            for i = 1:numel(app.data_path)
                if strcmp(filesep, app.data_path(end-(i-1)))
                    rname = app.data_path((end-(i-2)):end);
                    break;
                end
            end

            % Check if Valid
            if exist([rname '.xml'], 'file') || sorted_flag
                app.NoFolderSelectedLabel.Text = app.data_path;
                app.NoFolderSelectedLabel.Tooltip = app.data_path;
                app.data_set_flag = 1;
            else
                app.NoFolderSelectedLabel.Text ='Invalid Folder Selection';
                app.data_set_flag = 0;
            end

            % Navigational Clean Up
            app.last_path = app.data_path;
            cd(hpath);
        end

        % Button pushed function: SelectCalibrationFolderButton
        function calibration_folder_selection(app, event)
            hpath = pwd;

            app.calibration_path = uigetdir(app.last_path, ...
                'Select Calibration Folder');
            if app.calibration_path == 0
                uiwait(msgbox('Please Select a Valid Calibration Folder'));
                return;
            end
            cd(app.calibration_path);

            % Check Validity
            dir_list = dir;
            dir_list = dir_list(3:end);

            if ~isempty(dir_list)
                app.NoFolderSelectedLabel_2.Text = app.calibration_path;
                app.NoFolderSelectedLabel_2.Tooltip = app.calibration_path;
                app.calibration_set_flag = 1;
            else
                app.NoFolderSelectedLabel_2.Text = ...
                    'Invalid Folder Selection';
                app.calibration_set_flag = 0;
            end

            % Navigational Clean Up
            app.last_path = app.data_path;
            cd(hpath);
        end

        % Button pushed function: SelectPositionFileButton
        function postion_file_selection(app, event)
            hpath = pwd;
            if ~strcmp(app.last_path, '')
                cd(app.last_path);
            end

            [pos_file_name, pos_file_path] = uigetfile('*.xy', ...
                'Select Position File');

            if pos_file_name == 0
                uiwait(msgbox('Please Select a Valid Position File'));
                return;
            elseif ~strcmp(pos_file_name(end-2:end), '.xy')
                uiwait(msgbox('Please Select a Valid Position File'));
                return;
            else
                app.position_file_flag = 1;
                app.position_file_path = [pos_file_path, pos_file_name];
                app.NoFileSelectedLabel.Text = app.position_file_path;
                app.NoFileSelectedLabel.Tooltip = app.position_file_path;
            end

            % Navigational Clean Up
            app.last_path = app.data_path;
            cd(hpath);
        end

        % Value changed function: AutomaticCheckBox
        function line_detection_switch(app, event)
            value = app.AutomaticCheckBox.Value;
            
            if value == 1
                % Automatic Line Detection is Selected
                app.NumberofLinesinRawImageEditField.Enable = 0;
                app.NumberofLinesinRawImageEditFieldLabel.Enable = 0;
                
            else
                % Manual Line Detection is Selected
                app.NumberofLinesinRawImageEditField.Enable = 1;
                app.NumberofLinesinRawImageEditFieldLabel.Enable = 1;
                
            end
        end

        % Value changed function: AutomaticCheckBox_2
        function spectral_boundaries_switch(app, event)
            value = app.AutomaticCheckBox_2.Value;
            
            if value == 1
                % Automatic Spectral Boundary Finding is Selected
                app.SpectralFindingGranularityEditField.Visible = 1;
                app.SpectralFindingGranularityEditFieldLabel.Visible = 1;
                app.SpectralBoundariesnmTextArea.Visible = 0;
                app.SpectralBoundariesnmTextAreaLabel.Visible = 0;
                
            else
                % Manual Boundary Setting is Selected
                app.SpectralFindingGranularityEditField.Visible = 0;
                app.SpectralFindingGranularityEditFieldLabel.Visible = 0;
                app.SpectralBoundariesnmTextArea.Visible = 1;
                app.SpectralBoundariesnmTextAreaLabel.Visible = 1;
                
            end
        end

        % Selection changed function: 
        % SelectProcessingModeButtonGroup
        function proc_mode_switch(app, event)
            
            if app.IndividualImagesButton.Value == 1
                app.ImageTilingPanel.Enable = 'off';
                
                % Remove Position File from Input
                app.position_file_path = '';
                app.position_file_flag = 0;
                app.NoFileSelectedLabel.Text = 'No File Selected';
                app.NoFileSelectedLabel.Tooltip = 'No File selected';
                
                
            elseif app.ImageStackButton.Value == 1
                app.ImageTilingPanel.Enable = 'on';
                
            elseif app.VideoButton.Value == 1
                app.ImageTilingPanel.Enable = 'on';
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrallySplitSweptFieldConfocalProcessingLabel = uilabel(app.UIFigure);
            app.SpectrallySplitSweptFieldConfocalProcessingLabel.Text = 'Spectrally-Split Swept Field Confocal Processing';
            app.OutputImageOptionsPanel = uipanel(app.UIFigure);
            app.OutputImageOptionsPanel.Title = 'Output Image Options';
            app.SaveIntermediaryImagesCheckBox = uicheckbox(app.OutputImageOptionsPanel);
            app.SaveIntermediaryImagesCheckBox.Tooltip = {'Select if you want to save intermediary steps from the processed images.'; 'NOTE: This will slow down your processing speeds.'};
            app.SaveIntermediaryImagesCheckBox.Text = 'Save Intermediary Images';
            app.SaveIntermediaryImagesCheckBox.Value = true;
            app.OutputImageBitDepthDropDownLabel = uilabel(app.OutputImageOptionsPanel);
            app.OutputImageBitDepthDropDownLabel.Text = 'Output Image Bit Depth';
            app.OutputImageBitDepthDropDown = uidropdown(app.OutputImageOptionsPanel);
            app.OutputImageBitDepthDropDown.Items = {'8 Bit', '16 Bit', '32 Bit', '64 Bit'};
            app.OutputImageBitDepthDropDown.Tooltip = {'Select the Bit Depth output images should contain.'};
            app.OutputImageBitDepthDropDown.Value = '16 Bit';
            app.ImageSaveFileTypeButtonGroup = uibuttongroup(app.OutputImageOptionsPanel);
            app.ImageSaveFileTypeButtonGroup.Title = 'Image Save File Type';
            app.tifButton = uiradiobutton(app.ImageSaveFileTypeButtonGroup);
            app.tifButton.Tooltip = {'Select for all output images to be saved as .tif'};
            app.tifButton.Text = '.tif';
            app.tifButton.Value = true;
            app.SelectProcessingModeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectProcessingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @proc_mode_switch, true);
            app.SelectProcessingModeButtonGroup.Tooltip = {'Select which form of the processing to use.'};
            app.SelectProcessingModeButtonGroup.Title = 'Select Processing Mode';
            app.IndividualImagesButton = uitogglebutton(app.SelectProcessingModeButtonGroup);
            app.IndividualImagesButton.Tooltip = {'Individual images are images with no overlapping areas and can be a random assortment.'};
            app.IndividualImagesButton.Text = 'Individual Images';
            app.ImageStackButton = uitogglebutton(app.SelectProcessingModeButtonGroup);
            app.ImageStackButton.Tooltip = {'Image Stack is a set of images that have been acquired to make a data cube.'};
            app.ImageStackButton.Text = 'Image Stack';
            app.ImageStackButton.Value = true;
            app.VideoButton = uitogglebutton(app.SelectProcessingModeButtonGroup);
            app.VideoButton.Tooltip = {'Video is a set of images acquired either in 2D or 3D over time to generate a data cube.'};
            app.VideoButton.Text = 'Video';
            app.WavelengthRangenmPanel = uipanel(app.UIFigure);
            app.WavelengthRangenmPanel.Title = 'Wavelength Range [nm]';
            app.MinimumEditFieldLabel = uilabel(app.WavelengthRangenmPanel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.WavelengthRangenmPanel, 'numeric');
            app.MinimumEditField.Value = 400;
            app.MaximumEditFieldLabel = uilabel(app.WavelengthRangenmPanel);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.WavelengthRangenmPanel, 'numeric');
            app.MaximumEditField.Value = 700;
            app.ProcessDataButton = uibutton(app.UIFigure, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @process_data, true);
            app.ProcessDataButton.Tooltip = {'Push to start processing data.'};
            app.ProcessDataButton.Text = 'Process Data';
            app.DataSelectionPanel = uipanel(app.UIFigure);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.SelectFoldertoProcessButton = uibutton(app.DataSelectionPanel, 'push');
            app.SelectFoldertoProcessButton.ButtonPushedFcn = createCallbackFcn(app, @data_folder_selection, true);
            app.SelectFoldertoProcessButton.Tooltip = {'Push to select the folder containg all the data to process.'};
            app.SelectFoldertoProcessButton.Text = 'Select Folder to Process';
            app.DataOrderDropDownLabel = uilabel(app.DataSelectionPanel);
            app.DataOrderDropDownLabel.Text = 'Data Order';
            app.DataOrderDropDown = uidropdown(app.DataSelectionPanel);
            app.DataOrderDropDown.Items = {'XYZT', 'TXYZ'};
            app.DataOrderDropDown.Tooltip = {'The order the data was recorded. '};
            app.DataOrderDropDown.Value = 'XYZT';
            app.NoFolderSelectedLabel = uilabel(app.DataSelectionPanel);
            app.NoFolderSelectedLabel.Text = 'No Folder Selected';
            app.CalibrationSelectionPanel = uipanel(app.UIFigure);
            app.CalibrationSelectionPanel.Title = 'Calibration Selection';
            app.SelectCalibrationFolderButton = uibutton(app.CalibrationSelectionPanel, 'push');
            app.SelectCalibrationFolderButton.ButtonPushedFcn = createCallbackFcn(app, @calibration_folder_selection, true);
            app.SelectCalibrationFolderButton.Tooltip = {'Push to select the folder containg all the calibration files.'};
            app.SelectCalibrationFolderButton.Text = 'Select Calibration Folder';
            app.NoFolderSelectedLabel_2 = uilabel(app.CalibrationSelectionPanel);
            app.NoFolderSelectedLabel_2.Text = 'No Folder Selected';
            app.LineDetectionPanel = uipanel(app.UIFigure);
            app.LineDetectionPanel.Title = 'Line Detection';
            app.AutomaticCheckBox = uicheckbox(app.LineDetectionPanel);
            app.AutomaticCheckBox.ValueChangedFcn = createCallbackFcn(app, @line_detection_switch, true);
            app.AutomaticCheckBox.Tooltip = {'Select if you want the code to automatically detect how many lines/pinholes are present in the collected data. It is better to simply set it manually.'};
            app.AutomaticCheckBox.Text = 'Automatic';
            app.NumberofLinesinRawImageEditFieldLabel = uilabel(app.LineDetectionPanel);
            app.NumberofLinesinRawImageEditFieldLabel.Text = 'Number of Lines in Raw Image';
            app.NumberofLinesinRawImageEditField = uieditfield(app.LineDetectionPanel, 'numeric');
            app.NumberofLinesinRawImageEditField.Tooltip = {'The number of lines/pinholes present in raw images.'};
            app.NumberofLinesinRawImageEditField.Value = 32;
            app.SpectralBoundariesPanel = uipanel(app.UIFigure);
            app.SpectralBoundariesPanel.Title = 'Spectral Boundaries';
            app.AutomaticCheckBox_2 = uicheckbox(app.SpectralBoundariesPanel);
            app.AutomaticCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @spectral_boundaries_switch, true);
            app.AutomaticCheckBox_2.Tooltip = {'Select if you want the spectral boundaries to be automatically determined. NOTE: This method just gives a reasonable estimate of spectral boundaries, not for final processing.'};
            app.AutomaticCheckBox_2.Text = 'Automatic';
            app.SpectralFindingGranularityEditFieldLabel = uilabel(app.SpectralBoundariesPanel);
            app.SpectralFindingGranularityEditFieldLabel.Visible = 'off';
            app.SpectralFindingGranularityEditFieldLabel.Text = 'Spectral Finding Granularity';
            app.SpectralFindingGranularityEditField = uieditfield(app.SpectralBoundariesPanel, 'numeric');
            app.SpectralFindingGranularityEditField.Visible = 'off';
            app.SpectralFindingGranularityEditField.Tooltip = {'The granularity to be used to search the wavelength space for peaks.'};
            app.SpectralFindingGranularityEditField.Value = 10;
            app.SpectralBoundariesnmTextAreaLabel = uilabel(app.SpectralBoundariesPanel);
            app.SpectralBoundariesnmTextAreaLabel.Text = 'Spectral Boundaries [nm]';
            app.SpectralBoundariesnmTextArea = uitextarea(app.SpectralBoundariesPanel);
            app.SpectralBoundariesnmTextArea.Tooltip = {'Wavelengths that seperate the total range for bins.'};
            app.SpectralBoundariesnmTextArea.Value = {'490'; '550'; ''};
            app.ImageTilingPanel = uipanel(app.UIFigure);
            app.ImageTilingPanel.Title = 'Image Tiling';
            app.PixelSizeumEditFieldLabel = uilabel(app.ImageTilingPanel);
            app.PixelSizeumEditFieldLabel.Text = 'Pixel Size [um]';
            app.PixelSizeumEditField = uieditfield(app.ImageTilingPanel, 'numeric');
            app.PixelSizeumEditField.Tooltip = {'Size of the pixel.'};
            app.PixelSizeumEditField.Value = 0.266;
            app.SelectPositionFileButton = uibutton(app.ImageTilingPanel, 'push');
            app.SelectPositionFileButton.ButtonPushedFcn = createCallbackFcn(app, @postion_file_selection, true);
            app.SelectPositionFileButton.Tooltip = {'Push to select the position file for the data to process.'};
            app.SelectPositionFileButton.Text = 'Select Position File';
            app.NoFileSelectedLabel = uilabel(app.ImageTilingPanel);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.StartingOffsetCorrectionPanel = uipanel(app.UIFigure);
            app.StartingOffsetCorrectionPanel.Tooltip = {'This is a system specific calibration value. It represents a correction from the system determined starting offset. Once set for a system, it remains constant throughout.'};
            app.StartingOffsetCorrectionPanel.Title = 'Starting Offset Correction';
            app.StartingOffsetCorrectionEditField = uieditfield(app.StartingOffsetCorrectionPanel, 'numeric');
            app.StartingOffsetCorrectionEditField.Tooltip = {'This is a system specific calibration value. It represents a correction from the system determined starting offset. Once set for a system, it remains constant throughout.'};
            app.StartingOffsetCorrectionEditField.Value = -3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSFC_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end